classdef ESRA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
        saveButton                      matlab.ui.control.Button
        exitButton                      matlab.ui.control.Button
        loadLastConfiguration           matlab.ui.control.Button
        pauseButton                     matlab.ui.control.Button
        terminateButton                 matlab.ui.control.Button
        buttonStart                     matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        BuildingCarConfiguration        matlab.ui.container.Tab
        parkingMethod                   matlab.ui.control.DropDown
        ParkingMethodLabel              matlab.ui.control.Label
        floorHeightSpinner              matlab.ui.control.Spinner
        FloorheightSpinnerLabel         matlab.ui.control.Label
        carCapacityFactorSpinner        matlab.ui.control.Spinner
        CarcapacityfactorLabel          matlab.ui.control.Label
        carVelocitySpinner              matlab.ui.control.Spinner
        CarvelocitySpinnerLabel         matlab.ui.control.Label
        passengerTransferTimeSpinner    matlab.ui.control.Spinner
        PassengertransfertimeSpinnerLabel  matlab.ui.control.Label
        doorClosingTimeSpinner          matlab.ui.control.Spinner
        DoorclosingtimeSpinnerLabel     matlab.ui.control.Label
        doorOpeningTimeSpinner          matlab.ui.control.Spinner
        DooropeningtimeSpinnerLabel     matlab.ui.control.Label
        carCapacitySpinner              matlab.ui.control.Spinner
        CarCapacitySpinnerLabel         matlab.ui.control.Label
        NumberofcarsPanel               matlab.ui.container.Panel
        carsSliderFixed                 matlab.ui.control.Slider
        FixednumberofcarsLabel          matlab.ui.control.Label
        carsStepSize                    matlab.ui.control.DropDown
        StepSizeDropDownLabel_2         matlab.ui.control.Label
        carsSliderRange                 matlab.ui.control.RangeSlider
        RangeofnumbersoffloorsLabel_2   matlab.ui.control.Label
        carsLampFixed                   matlab.ui.control.Lamp
        carsLampRange                   matlab.ui.control.Lamp
        carsFixed                       matlab.ui.control.Label
        carsMax                         matlab.ui.control.Label
        carsMin                         matlab.ui.control.Label
        carsLabelMax                    matlab.ui.control.Label
        carsLabelMin                    matlab.ui.control.Label
        panelNumFloors                  matlab.ui.container.Panel
        floorsSliderFixed               matlab.ui.control.Slider
        FixednumberoffloorsLabel        matlab.ui.control.Label
        floorsStepSize                  matlab.ui.control.DropDown
        StepSizeDropDownLabel           matlab.ui.control.Label
        floorsSliderRange               matlab.ui.control.RangeSlider
        RangeofnumbersoffloorsLabel     matlab.ui.control.Label
        floorsLampFixed                 matlab.ui.control.Lamp
        floorsLampRange                 matlab.ui.control.Lamp
        floorsFixed                     matlab.ui.control.Label
        floorsMin                       matlab.ui.control.Label
        floorsMax                       matlab.ui.control.Label
        floorsMaxLabel                  matlab.ui.control.Label
        floorsMinLabel                  matlab.ui.control.Label
        TrafficConfiguration            matlab.ui.container.Tab
        PassengerArrivalButtonGroup     matlab.ui.container.ButtonGroup
        arrivalRateRadioButton          matlab.ui.control.RadioButton
        arrivalRateSpinner              matlab.ui.control.Spinner
        radioButtonNoNewPassenger       matlab.ui.control.RadioButton
        TrafficComponentsConfigurationPanel  matlab.ui.container.Panel
        trafficFixedPanel               matlab.ui.container.Panel
        trafficFixedOutgoing            matlab.ui.control.Label
        trafficFixedOutgoingSlider      matlab.ui.control.Slider
        InterfloortrafficLabel_2        matlab.ui.control.Label
        trafficFixedInterfloorSlider    matlab.ui.control.Slider
        InterfloortrafficLabel          matlab.ui.control.Label
        trafficFixedInterfloor          matlab.ui.control.Label
        trafficFixedIncomingSlider      matlab.ui.control.Slider
        FixedincomingtrafficLabel       matlab.ui.control.Label
        trafficFixedIncoming            matlab.ui.control.Label
        trafficLampFixed                matlab.ui.control.Lamp
        trafficIntervalPanel            matlab.ui.container.Panel
        trafficInterfloorMax            matlab.ui.control.Label
        trafficInterfloorMin            matlab.ui.control.Label
        trafficInterfloorLabelMax       matlab.ui.control.Label
        trafficInterfloorLabelMin       matlab.ui.control.Label
        trafficLampRange                matlab.ui.control.Lamp
        trafficIncomingMax              matlab.ui.control.Label
        trafficIncomingMin              matlab.ui.control.Label
        trafficIncomingLabelMax         matlab.ui.control.Label
        trafficIncomingLabelMin         matlab.ui.control.Label
        trafficInterfloorSliderRange    matlab.ui.control.RangeSlider
        InterfloortrafficSliderLabel    matlab.ui.control.Label
        trafficIncomingSliderRange      matlab.ui.control.RangeSlider
        IncomingtrafficSliderLabel      matlab.ui.control.Label
        ControlMethod                   matlab.ui.container.Tab
        ControlMethodButtonGroup        matlab.ui.container.ButtonGroup
        MetaheuristicsButtonGroup       matlab.ui.container.ButtonGroup
        deVariant                       matlab.ui.control.DropDown
        VariantDropDownLabel            matlab.ui.control.Label
        psoC3                           matlab.ui.control.Spinner
        CrossoverrateLabel_13           matlab.ui.control.Label
        CrossoverrateLabel_12           matlab.ui.control.Label
        mutationButtonGroup             matlab.ui.container.ButtonGroup
        frequency                       matlab.ui.control.RadioButton
        swap                            matlab.ui.control.RadioButton
        uniform                         matlab.ui.control.RadioButton
        block                           matlab.ui.control.RadioButton
        scramble                        matlab.ui.control.RadioButton
        deSelectionButton               matlab.ui.control.RadioButton
        deCrossoverRate                 matlab.ui.control.Spinner
        CrossoverrateLabel_11           matlab.ui.control.Label
        deF                             matlab.ui.control.Spinner
        JumpprobLabel                   matlab.ui.control.Label
        CrossoverrateLabel_10           matlab.ui.control.Label
        acoAlpha                        matlab.ui.control.Spinner
        psoWstart                       matlab.ui.control.Spinner
        psoWend                         matlab.ui.control.Spinner
        psoC1                           matlab.ui.control.Spinner
        psoC2                           matlab.ui.control.Spinner
        CrossoverrateLabel_9            matlab.ui.control.Label
        CrossoverrateLabel_8            matlab.ui.control.Label
        acoBeta                         matlab.ui.control.Spinner
        CrossoverrateLabel_7            matlab.ui.control.Label
        acoQ                            matlab.ui.control.Spinner
        CrossoverrateLabel_6            matlab.ui.control.Label
        acoRho                          matlab.ui.control.Spinner
        CrossoverrateLabel_5            matlab.ui.control.Label
        CrossoverrateLabel_4            matlab.ui.control.Label
        gaSelectionButtonGroup          matlab.ui.container.ButtonGroup
        gaSelectionStochUnifButton      matlab.ui.control.RadioButton
        gaSelectionRouletteButton       matlab.ui.control.RadioButton
        gaSelectionTournamentButton     matlab.ui.control.RadioButton
        gaCrossoverButtonGroup          matlab.ui.container.ButtonGroup
        crossoverscatteredButton        matlab.ui.control.RadioButton
        crossoversinglepointButton      matlab.ui.control.RadioButton
        crossovertwopointButton         matlab.ui.control.RadioButton
        gaCrossoverRateSpinner          matlab.ui.control.Spinner
        CrossoverrateLabel              matlab.ui.control.Label
        gaMutationRateSpinner           matlab.ui.control.Spinner
        MutationrateSpinnerLabel        matlab.ui.control.Label
        maxIterationsSpinner            matlab.ui.control.Spinner
        CrossoverrateLabel_3            matlab.ui.control.Label
        populationSizeSpinner           matlab.ui.control.Spinner
        CrossoverrateLabel_2            matlab.ui.control.Label
        gaSelectionButton               matlab.ui.control.RadioButton
        acoSelectionButton              matlab.ui.control.RadioButton
        psoSelectionButton              matlab.ui.control.RadioButton
        metaheuristicsRadioButton       matlab.ui.control.RadioButton
        objectiveFunctionDropDown       matlab.ui.control.DropDown
        ObjectiveFunctionLabel          matlab.ui.control.Label
        buttonMarkovDecisionProcess     matlab.ui.control.RadioButton
        buttonNearestCarMethod          matlab.ui.control.RadioButton
        SimulationConfiguration         matlab.ui.container.Tab
        referenceTimeSpinner            matlab.ui.control.Spinner
        ReferencetimeSpinnerLabel       matlab.ui.control.Label
        numSimulationsSpinner           matlab.ui.control.Spinner
        NumberofsimulationsSpinnerLabel  matlab.ui.control.Label
        simulationDurationSpinner       matlab.ui.control.Spinner
        SimulationdurationSpinnerLabel  matlab.ui.control.Label
        dataTypeButtonGroup             matlab.ui.container.ButtonGroup
        numberOfRuns                    matlab.ui.control.Spinner
        NumberofrunsSpinnerLabel        matlab.ui.control.Label
        parameterSearch                 matlab.ui.control.CheckBox
        dataTypeNewTrafficRB            matlab.ui.control.RadioButton
        dataTypeRecordedTrafficRB       matlab.ui.control.RadioButton
        dataTypeNewTrafficwithCustomInitialsRB  matlab.ui.control.RadioButton
        NumberofinitialpassengersSpinnerLabel  matlab.ui.control.Label
        numInitialPassengersSpinner     matlab.ui.control.Spinner
        dispatchPeriodOption            matlab.ui.container.ButtonGroup
        dispatchPeriodSpinner           matlab.ui.control.Spinner
        DispatchperiodLabel             matlab.ui.control.Label
        dispatchPeriod                  matlab.ui.control.RadioButton
        oneShot                         matlab.ui.control.RadioButton
        displayTab                      matlab.ui.container.Tab
        counterLabel                    matlab.ui.control.Label
        countersTable                   matlab.ui.control.Table
        carsLabel                       matlab.ui.control.Label
        resultsTable                    matlab.ui.control.Table
        WaitingdownpassengersLabel      matlab.ui.control.Label
        WaitinguppassengersLabel        matlab.ui.control.Label
        carTable                        matlab.ui.control.Table
        passengerUpTable                matlab.ui.control.Table
        passengerDownTable              matlab.ui.control.Table
        simulationSpeedSlider           matlab.ui.control.Slider
        DisplayspeedLabel               matlab.ui.control.Label
        displayTabularDataCheckBox      matlab.ui.control.CheckBox
        displayTrafficFlowCheckBox      matlab.ui.control.CheckBox
        displayFlowUIAxes               matlab.ui.control.UIAxes
    end


    properties (Access = public)
        stopFlag (1,1) logical = false % Flag to stop the experiment
        pauseFlag(1,1) logical = false;
        exp=Experiment();
    end
    %BUILDING CONFIGURATION
    methods (Access = private)
        
        function  setRangeSlider(app,round_base,minDisplay,maxDisplay,fixedDisplay,triggeredSlider,otherSlider,triggeredLamp,otherLamp)
            % custom helper method for floors and cars sliders 
             slider_value = round(triggeredSlider.Value,round_base);
             majorTicks = 0:100;
             if isscalar(slider_value)
                 % determine which discrete option the current value is closest to.
                 [~, minIdx ] = min(abs(slider_value  - majorTicks));
                 % move the slider to that option
                 slider_value=majorTicks(minIdx);
             else
                % determine which discrete option the current value is closest to.
                 [~, minIdx1] = min(abs(slider_value(1) - majorTicks));
                 [~, minIdx2] = min(abs(slider_value(2) - majorTicks));
                 % move the slider to that option
                 slider_value=[majorTicks(minIdx1),majorTicks(minIdx2)];
             end

             triggeredSlider.Value = slider_value; % make slider and edit box linked
             swapLamps(app,triggeredLamp,otherLamp);
             if isscalar(slider_value)
                 slider_value=[slider_value,slider_value];    
                 if ~isempty(otherSlider)
                  otherSlider.Value = slider_value;
                 end
                  fixedDisplay.Text=num2str(slider_value(1));
             end   
             if ~isempty(minDisplay) 
                 minDisplay.Text=num2str(slider_value(1));
                 maxDisplay.Text=num2str(slider_value(2));        
             end
              triggeredSlider.Value
        end
        
        function swapLamps(app,triggeredLamp,otherLamp)
            triggeredLamp.Color=[0.,1.,0.];  % make green the triggered lamp
            otherLamp.Color=[0.65,0.65,0.65];% make gray the unactivated lamp
        end

        function SavePanelImage(app)
                   % Ensure UI is fully rendered
                drawnow;
                exportapp(app.UIFigure,'app_screenshot.png');
        end
        
        function   resetFlags(app)
            app.stopFlag = false; % Flag to stop the experiment
            app.pauseFlag= false;
            app.exp.exitFlag=false;
            app.pauseButton.Text = 'Pause';       % Revert button text to "Pause"
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: floorsSliderRange
        function floorsSliderRangeValueChanged(app, event)
              setRangeSlider(app,0,app.floorsMin,app.floorsMax,app.floorsFixed,app.floorsSliderRange,app.floorsSliderFixed,app.floorsLampRange,app.floorsLampFixed);       
        end

        % Value changed function: floorsSliderFixed
        function floorsSliderFixedValueChanged(app, event)
          setRangeSlider(app,0,app.floorsMin,app.floorsMax,app.floorsFixed,app.floorsSliderFixed,app.floorsSliderRange,app.floorsLampFixed,app.floorsLampRange);       
          app.floorsStepSize.Value= "1" ; 
        end

        % Value changed function: carsSliderRange
        function carsSliderRangeValueChanged(app, event)
           setRangeSlider(app,0,app.carsMin,app.carsMax,app.carsFixed,app.carsSliderRange,app.carsSliderFixed,app.carsLampRange,app.carsLampFixed);         
             app.carsStepSize.Value= "1" ; 
        end

        % Value changed function: carsSliderFixed
        function carsSliderFixedValueChanged(app, event)
          setRangeSlider(app,0,app.carsMin,app.carsMax,app.carsFixed,app.carsSliderFixed,app.carsSliderRange,app.carsLampFixed,app.carsLampRange); 
        end

        % Value changed function: trafficFixedIncomingSlider
        function trafficFixedIncomingSliderValueChanged(app, event)
           setRangeSlider(app,1,app.trafficIncomingMin,app.trafficIncomingMax,app.trafficFixedIncoming,app.trafficFixedIncomingSlider,app.trafficIncomingSliderRange,app.trafficLampFixed,app.trafficLampRange);             
        end

        % Value changed function: trafficFixedInterfloorSlider
        function trafficFixedInterfloorSliderValueChanged(app, event)
            setRangeSlider(app,1,app.trafficInterfloorMin,app.trafficInterfloorMax,app.trafficFixedInterfloor,app.trafficFixedInterfloorSlider,app.trafficInterfloorSliderRange,app.trafficLampFixed,app.trafficLampRange);         
            
        end

        % Value changed function: trafficIncomingSliderRange
        function trafficIncomingSliderRangeValueChanged(app, event)
           setRangeSlider(app,1,app.trafficIncomingMin,app.trafficIncomingMax,app.trafficFixedIncoming,app.trafficIncomingSliderRange,app.trafficFixedIncomingSlider,app.trafficLampRange,app.trafficLampFixed);         

        end

        % Value changed function: trafficInterfloorSliderRange
        function trafficInterfloorSliderRangeValueChanged(app, event)
            setRangeSlider(app,1,app.trafficInterfloorMin,app.trafficInterfloorMax,app.trafficFixedInterfloor,app.trafficInterfloorSliderRange,app.trafficFixedInterfloorSlider,app.trafficLampRange,app.trafficLampFixed);             
        end

        % Button pushed function: buttonStart
        function buttonStartButtonPushed(app, event)
            addpath(genpath('utils'));  % Adds the 'classes' folder and subfolders
            addpath(genpath('decision'));  % Adds the 'classes' folder and subfolders
            addpath(genpath('dataStructures'));  % Adds the 'classes' folder and subfolders
            addpath(genpath('objFuns'));  % Adds the 'classes' folder and subfolders
            startData.parameterSearch=app.parameterSearch.Value;
            startData.numberOfRuns=app.numberOfRuns.Value;
            % Dropdown items are string by default. Convert them to numeric

           % BUILDING: load building data from building configuration
            % BUILDING: number of floors and floor height
            startData.NFmin = str2double(app.floorsMin.Text);
            startData.NFmax = str2double(app.floorsMax.Text);
            startData.NFstep = str2double(app.floorsStepSize.Value);
            startData.floorHeight= app.floorHeightSpinner.Value;

            % CAR: load car data from building configuration
            % CAR: number of cars
            startData.NCmin = str2double(app.carsMin.Text);
            startData.NCmax = str2double(app.carsMax.Text);
            % parking algorithm(s)

            startData.parkingAlgorithm=[app.parkingMethod];

            % CAR: door opening, closing time and passenger transfer time
            startData.doorOpeningTime = app.doorOpeningTimeSpinner.Value;
            startData.doorClosingTime = app.doorClosingTimeSpinner.Value;
            startData.passengerTransferTime = app.passengerTransferTimeSpinner.Value;
            startData.carCapacity = app.carCapacitySpinner.Value;
            startData.carVelocity = app.carVelocitySpinner.Value;
            startData.carCapacityFactor = app.carCapacityFactorSpinner.Value;

            % TRAFFIC: load traffic data from building configuration

            startData.INCmin =  app.trafficFixedIncomingSlider.Value;
            startData.INCmax = app.trafficFixedIncomingSlider.Value;
            %startData.INCmin = slider_values(1);
            %startData.INCmax = slider_values(2);

            startData.INTmin = app.trafficFixedInterfloorSlider.Value;
            startData.INTmax = app.trafficFixedInterfloorSlider.Value;
            %startData.INTmin = slider_values(1);
            %startData.INTmax = slider_values(2);

            if app.arrivalRateRadioButton.Value
                startData.arrivalRate=app.arrivalRateSpinner.Value;  %passenger arrival rate
            else
                startData.arrivalRate=Inf;
            end
            % SIMULATION:
            % load simulation data from the ui-tabConfigurationSimulation
            startData.refTime =app.referenceTimeSpinner.Value;            %simulation reference time(to remove initial transition effect)
            startData.endTime =app.simulationDurationSpinner.Value;       %simulation reference time(to remove initial transition effect)

            startData.numSimulations= app.numSimulationsSpinner.Value;    %number of simulations
            if app.dispatchPeriod.Value 
                    startData.decisionPeriod=app.dispatchPeriodSpinner.Value;   %simulation dispatching period
            else
                    startData.decisionPeriod=inf;
            end
            Simulator.getSetEndTime(app.simulationDurationSpinner.Value);


            % CONTROL METHOD:
            % objectiveFunctionDropDown

            if app.objectiveFunctionDropDown.Value =="Conventional Information"
                startData.objFun=str2func("objFunConventional1");
            elseif app.objectiveFunctionDropDown.Value =="Number of Passengers"
                startData.objFun=str2func("objFunNumPassengers");
            elseif app.objectiveFunctionDropDown.Value=="Destination Information"
                startData.objFun= str2func("objFunDestination");
            end
            % Control Method
            if(app.buttonNearestCarMethod.Value)
                startData.stateUpdateTypeForNextDecision = "fixed";
                startData.availableInformation=1;
                startData.decisionMaker = NearestCarDispatcher( startData);
            elseif(app.metaheuristicsRadioButton.Value)
                startData.stateUpdateTypeForNextDecision = "fixed";% "flexible";
                startData.G= app.maxIterationsSpinner.Value;  % maximum number of generations -
                startData.nPop= app.populationSizeSpinner.Value;    % Number of chromosomes
                startData.mutationFunction=app.mutationButtonGroup.SelectedObject.Text;

                if app.gaSelectionButton.Value
                    startData.Pc=app.gaCrossoverRateSpinner.Value;  % Crossover rate
                    startData.Pm=app.gaMutationRateSpinner.Value;  % Mutation rate 0.05 !!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!
                    startData.gaMethod=1;
                    startData.crossoverFcn=app.gaCrossoverButtonGroup.SelectedObject.Text;
                    startData.selectionFcn=app.gaSelectionButtonGroup.SelectedObject.Text;
                    startData.decisionMaker = GA(startData);
                elseif app.acoSelectionButton.Value                    
                    startData.decisionMaker = ACO(startData);
                elseif app.psoSelectionButton.Value
                    startData.w_start=app.psoWstart.Value;
                    startData.w_end=app.psoWend.Value;
                    startData.c1= app.psoC1;
                    startData.c2=app.psoC2;
                    startData.c3=app.psoC3;
                    startData.decisionMaker = PSO(startData);

                elseif app.deSelectionButton.Value
                    startData.crossoverRate=app.deCrossoverRate.Value;
                    startData.F=app.deF.Value;
                    startData.variant= app.deVariant.Value;
                    startData.decisionMaker = DE(startData);
                end
                startData.availableInformation=1;
            elseif(get(handles.mdpRB,'Value'))
                [A_star_universe,Svec2id,~,~,~]= MDPloadData(startData.mdpFileName);%'\6floor_20Haziran.mat');%[A_star_cell,Svec2id,Sid2vec,A,S]
                startData.decisionMaker =  MDP( "flexible",Svec2id,A_star_universe);
                % startData.method='MDP';
                %   startData.displayOption=startData.displayOption+0.5;%display update for MDP in Engine
            end

           
            %DATA TYPE:
            if ~isfield(app.SimulationConfiguration.UserData, 'dataType')
                app.SimulationConfiguration.UserData.dataType = 1; % Default value
                app.SimulationConfiguration.UserData.fileName = ""; % Default value
            end
            startData.dataType = app.SimulationConfiguration.UserData.dataType;
            startData.fileName = app.SimulationConfiguration.UserData.fileName;
            startData.numInitialPassengers = app.numInitialPassengersSpinner.Value;

            % DISPLAY WINDOW
            Simulator.getSetDisplayTrafficFlow(app.displayTrafficFlowCheckBox.Value);
            if app.displayTrafficFlowCheckBox.Value
                app.displayFlowUIAxes.Visible = 'on';
            end
            %SIMULATION
           % startData.displayTrafficFlow = app.displayTrafficFlowCheckBox.Value;
           % startData.displayTrafficDataCheckBox = app.displayTabularDataCheckBox.Value;
            Simulator.setgetSpeed( app.simulationSpeedSlider.Value );
            Simulator.getSetDisplayTabularData(app.displayTabularDataCheckBox.Value);

            app.resultsTable.ColumnWidth = {50};  
            s = uistyle('HorizontalAlignment', 'left');
            addStyle(app.resultsTable, s); % Apply to entire table
            %START THE EXPERIMENT
            if startData.INCmin + startData.INTmin >100
                sprintf("Sum of intervals cannot be greater than 100.")
                % Notify the user that the data has been saved
                uialert(app.UIFigure, 'Alert', 'Sum of intervals cannot be greater than 100.');
            else
                resetFlags(app); 
                app.exp.run(startData, app);
            end


        end

        % Button pushed function: terminateButton
        function terminateButtonPushed(app, event)
            % Initialize the stop flag
            app.stopFlag= true;%setappdata(app.UIFigure, 'stopExperiment', true);
            app.pauseFlag= false;%setappdata(app.UIFigure, 'pauseExperiment', false);
            app.pauseButton.Text = 'Pause';       % Revert button text to "Pause"
            cla(app.displayFlowUIAxes, 'reset');
            app.displayFlowUIAxes.Visible = 'off';
            app.carTable.Data = {};
            app.passengerUpTable.Data =  {};
            app.passengerDownTable.Data = {};
            app.resultsTable.Data = {};
        end

        % Button pushed function: pauseButton
        function pauseButtonPushed(app, event)
          %getappdata(app.UIFigure, 'pauseExperiment');
            if app.pauseFlag  
              % Resume the experiment
                app.pauseFlag=false;
                app.pauseButton.Text = 'Pause';       % Revert button text to "Pause"
            else
                % Pause the experiment
                   app.pauseFlag= true;        
                app.pauseButton.Text = 'Continue';  % Change button text to "Continue"                  
            end    
        end

        % Value changed function: displayTrafficFlowCheckBox
        function displayTrafficFlowCheckBoxValueChanged(app, event)
            value = app.displayTrafficFlowCheckBox.Value;
            Simulator.getSetDisplayTrafficFlow(value);
            if value
                app.displayFlowUIAxes.Visible = 'on';
            else
                app.displayFlowUIAxes.Visible = 'off';
                Simulator.clearScreen(app);
            end
        end

        % Value changed function: trafficFixedOutgoingSlider
        function trafficFixedOutgoingSliderValueChanged(app, event)
            setRangeSlider(app,1,[],[],app.trafficFixedOutgoing,app.trafficFixedOutgoingSlider,[],app.trafficLampFixed,app.trafficLampRange);         

        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            % Allow the user to specify the file name and location
            [file, path] = uiputfile('*.mat', 'Save dataConf to MAT-file');

            % Check if the user canceled the file selection
            if isequal(file, 0)
                disp('User canceled the save operation.');
                return;
            end

            % Construct the full file path
            fullFilePath = fullfile(path, file);

            % Save dataConf to the specified file
            dataConf = evalin('base', 'dataConf'); % Retrieve dataConf from the workspace
            save(fullFilePath, 'dataConf');
        end

        % Button pushed function: exitButton
        function exitButtonPushed(app, event)
           app.exp.exitFlag = true;
            state = struct();
            props = properties(app);
            for i = 1:length(props)
                name = props{i};
                comp = app.(name);
                c = string(class(comp));
                
           
                % Handle numeric components
                if c == "matlab.ui.control.NumericEditField" || ...
                        c == "matlab.ui.control.CheckBox" || ...
                        c == "matlab.ui.control.Spinner" || ...
                        c == "matlab.ui.control.Slider" || ...                       
                        c== "matlab.ui.control.RadioButton" || ...  
                        c== "matlab.ui.control.RangeSlider" || ...  
                        c== "matlab.ui.control.RadioButton" 
                       
                    % Cast to numeric explicitly
                    state.(name) = double(comp.Value);
                elseif c == "matlab.ui.control.DropDown"
                   state.(name) = comp.Value;
                elseif  c == "matlab.ui.control.Lamp"               
                     state.(name).Color = comp.Color;
                elseif  c == "matlab.ui.control.Label" 
                     state.(name) = comp.Text;
                end
            end
            
           save('savedSettings.mat', 'state');
           SavePanelImage(app);  %Save panel image
           app.delete();       
         
        end

        % Button pushed function: loadLastConfiguration
        function loadLastConfigurationButtonPushed(app, event)

             if exist('savedSettings.mat', 'file')
                % Load saved state
                data = load('savedSettings.mat');
                appState = data.state;
                
                % Get all field names from saved state
                fields = fieldnames(appState);                
                % Apply saved values to UI components
                for i = 1:length(fields)
                    fieldName = fields{i};
                    % Check if this component exists in the app
                    if isprop(app, fieldName) && isvalid(app.(fieldName))
                         if  isa(app.(fieldName), 'matlab.ui.control.Lamp')            
                              app.(fieldName).Color = appState.(fieldName).Color;
                         elseif   isa(app.(fieldName),"matlab.ui.control.Label" )
                              app.(fieldName).Text = appState.(fieldName) ;   
                         else  
                             app.(fieldName).Value = appState.(fieldName) ;   
                        end
                    end
                end
            end
        end

        % Selection changed function: dataTypeButtonGroup
        function dataTypeChanged(app, event)
               selectedButton = app.dataTypeButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'New Traffic'
                    app.SimulationConfiguration.UserData.dataType=1;
                    app.SimulationConfiguration.UserData.fileName=""; 
                case 'Recorded Traffic'                   
                    [fileName,~] = uigetfile('*.mat','Select the recorded Traffic file');    
                    app.SimulationConfiguration.UserData.fileName=fileName;   
                    app.SimulationConfiguration.UserData.dataType=2;
                case 'New Traffic with Custom Initials'
                    [fileName,~] = uigetfile('*.xlsx','Select the Traffic initials file');    
                    app.SimulationConfiguration.UserData.fileName=fileName;  
                    app.SimulationConfiguration.UserData.dataType=3;
            end
                     drawnow; % Refresh the GUI
        end

        % Value changed function: simulationSpeedSlider
        function simulationSpeedSliderValueChanged(app, event)
            value = app.simulationSpeedSlider.Value;
            Simulator.setgetSpeed( value );
            app.displayFlowUIAxes.Visible = value;
        end

        % Value changed function: displayTabularDataCheckBox
        function displayTabularDataCheckBoxValueChanged(app, event)
            value = app.displayTabularDataCheckBox.Value ;
            Simulator.getSetDisplayTabularData(value);
            app.carTable.Visible = value;
            app.passengerUpTable.Visible = value;
            app.passengerDownTable.Visible = value;
            app.carTable.Data = {};
            app.passengerUpTable.Data =  {};
            app.passengerDownTable.Data = {};

        end

        % Value changed function: psoWstart
        function psoWstartValueChanged(app, event)
             % Silently adjust w_end if needed
            if app.psoWend.Value > app.psoWstart.Value
                app.psoWend.Value =app.psoWstart.Value;
            end
        end

        % Value changed function: psoWend
        function psoWendValueChanged(app, event)
             % Silently adjust w_end if it exceeds w_start
            if app.psoWend.Value > app.psoWstart.Value
                app.psoWend.Value = app.psoWstart.Value;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BuildingCarConfiguration = uitab(app.TabGroup);
            app.BuildingCarConfiguration.Title = 'Building Configuration & Car Configuration';
            app.panelNumFloors = uipanel(app.BuildingCarConfiguration);
            app.panelNumFloors.Title = 'Number of floors';
            app.floorsMinLabel = uilabel(app.panelNumFloors);
            app.floorsMinLabel.Text = 'Minimum number of floors :';
            app.floorsMaxLabel = uilabel(app.panelNumFloors);
            app.floorsMaxLabel.Text = 'Maximum number of floors :';
            app.floorsMax = uilabel(app.panelNumFloors);
            app.floorsMax.Text = '20';
            app.floorsMin = uilabel(app.panelNumFloors);
            app.floorsMin.Text = '20';
            app.floorsFixed = uilabel(app.panelNumFloors);
            app.floorsFixed.Text = '20';
            app.floorsLampRange = uilamp(app.panelNumFloors);
            app.floorsLampFixed = uilamp(app.panelNumFloors);
            app.RangeofnumbersoffloorsLabel = uilabel(app.panelNumFloors);
            app.RangeofnumbersoffloorsLabel.Text = 'Range of numbers of floors';
            app.floorsSliderRange = uislider(app.panelNumFloors, 'range');
            app.floorsSliderRange.Limits = [3 50];
            app.floorsSliderRange.MajorTicks = [3 5 10 15 20 25 30 35 40 45 50];
            app.floorsSliderRange.ValueChangedFcn = createCallbackFcn(app, @floorsSliderRangeValueChanged, true);
            app.floorsSliderRange.MinorTicks = [3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50];
            app.floorsSliderRange.Value = [20 20];
            app.StepSizeDropDownLabel = uilabel(app.panelNumFloors);
            app.StepSizeDropDownLabel.Text = 'Step Size';
            app.floorsStepSize = uidropdown(app.panelNumFloors);
            app.floorsStepSize.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30'};
            app.floorsStepSize.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30'};
            app.floorsStepSize.Value = '1';
            app.FixednumberoffloorsLabel = uilabel(app.panelNumFloors);
            app.FixednumberoffloorsLabel.Text = 'Fixed number of floors :';
            app.floorsSliderFixed = uislider(app.panelNumFloors);
            app.floorsSliderFixed.Limits = [3 50];
            app.floorsSliderFixed.MajorTicks = [3 5 10 15 20 25 30 35 40 45 50];
            app.floorsSliderFixed.ValueChangedFcn = createCallbackFcn(app, @floorsSliderFixedValueChanged, true);
            app.floorsSliderFixed.MinorTicks = [3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50];
            app.floorsSliderFixed.Value = 20;
            app.NumberofcarsPanel = uipanel(app.BuildingCarConfiguration);
            app.NumberofcarsPanel.Title = 'Number of cars';
            app.carsLabelMin = uilabel(app.NumberofcarsPanel);
            app.carsLabelMin.Text = 'Minimum number of cars :';
            app.carsLabelMax = uilabel(app.NumberofcarsPanel);
            app.carsLabelMax.Text = 'Maximum number of cars :';
            app.carsMin = uilabel(app.NumberofcarsPanel);
            app.carsMin.Text = '5';
            app.carsMax = uilabel(app.NumberofcarsPanel);
            app.carsMax.Text = '5';
            app.carsFixed = uilabel(app.NumberofcarsPanel);
            app.carsFixed.Text = '5';
            app.carsLampRange = uilamp(app.NumberofcarsPanel);
            app.carsLampFixed = uilamp(app.NumberofcarsPanel);
            app.RangeofnumbersoffloorsLabel_2 = uilabel(app.NumberofcarsPanel);
            app.RangeofnumbersoffloorsLabel_2.Text = 'Range of numbers of cars';
            app.carsSliderRange = uislider(app.NumberofcarsPanel, 'range');
            app.carsSliderRange.Limits = [2 10];
            app.carsSliderRange.MajorTicks = [2 3 4 5 6 7 8 9 10];
            app.carsSliderRange.ValueChangedFcn = createCallbackFcn(app, @carsSliderRangeValueChanged, true);
            app.carsSliderRange.MinorTicks = [2 3 4 5 6 7 8 9 10];
            app.carsSliderRange.Value = [5 5];
            app.StepSizeDropDownLabel_2 = uilabel(app.NumberofcarsPanel);
            app.StepSizeDropDownLabel_2.Text = 'Step Size';
            app.carsStepSize = uidropdown(app.NumberofcarsPanel);
            app.carsStepSize.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30'};
            app.carsStepSize.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30'};
            app.carsStepSize.Value = '1';
            app.FixednumberofcarsLabel = uilabel(app.NumberofcarsPanel);
            app.FixednumberofcarsLabel.Text = 'Fixed number of cars :';
            app.carsSliderFixed = uislider(app.NumberofcarsPanel);
            app.carsSliderFixed.Limits = [2 10];
            app.carsSliderFixed.MajorTicks = [2 3 4 5 6 7 8 9 10];
            app.carsSliderFixed.ValueChangedFcn = createCallbackFcn(app, @carsSliderFixedValueChanged, true);
            app.carsSliderFixed.MinorTicks = [2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50];
            app.carsSliderFixed.Value = 5;
            app.CarCapacitySpinnerLabel = uilabel(app.BuildingCarConfiguration);
            app.CarCapacitySpinnerLabel.Text = 'Car Capacity';
            app.carCapacitySpinner = uispinner(app.BuildingCarConfiguration);
            app.carCapacitySpinner.Limits = [0 50];
            app.carCapacitySpinner.Value = 10;
            app.DooropeningtimeSpinnerLabel = uilabel(app.BuildingCarConfiguration);
            app.DooropeningtimeSpinnerLabel.Text = 'Door opening time';
            app.doorOpeningTimeSpinner = uispinner(app.BuildingCarConfiguration);
            app.doorOpeningTimeSpinner.Step = 0.1;
            app.doorOpeningTimeSpinner.Limits = [0 10];
            app.doorOpeningTimeSpinner.Value = 2;
            app.DoorclosingtimeSpinnerLabel = uilabel(app.BuildingCarConfiguration);
            app.DoorclosingtimeSpinnerLabel.Text = 'Door closing time';
            app.doorClosingTimeSpinner = uispinner(app.BuildingCarConfiguration);
            app.doorClosingTimeSpinner.Step = 0.1;
            app.doorClosingTimeSpinner.Limits = [0 10];
            app.doorClosingTimeSpinner.Value = 2;
            app.PassengertransfertimeSpinnerLabel = uilabel(app.BuildingCarConfiguration);
            app.PassengertransfertimeSpinnerLabel.Text = 'Passenger transfer time';
            app.passengerTransferTimeSpinner = uispinner(app.BuildingCarConfiguration);
            app.passengerTransferTimeSpinner.Step = 0.1;
            app.passengerTransferTimeSpinner.Limits = [0 10];
            app.passengerTransferTimeSpinner.Value = 3;
            app.CarvelocitySpinnerLabel = uilabel(app.BuildingCarConfiguration);
            app.CarvelocitySpinnerLabel.Text = 'Car velocity';
            app.carVelocitySpinner = uispinner(app.BuildingCarConfiguration);
            app.carVelocitySpinner.Step = 0.1;
            app.carVelocitySpinner.Limits = [0.1 20];
            app.carVelocitySpinner.Value = 1.5;
            app.CarcapacityfactorLabel = uilabel(app.BuildingCarConfiguration);
            app.CarcapacityfactorLabel.Text = 'Car capacity factor';
            app.carCapacityFactorSpinner = uispinner(app.BuildingCarConfiguration);
            app.carCapacityFactorSpinner.Step = 0.1;
            app.carCapacityFactorSpinner.Limits = [0 1];
            app.carCapacityFactorSpinner.Value = 1;
            app.FloorheightSpinnerLabel = uilabel(app.BuildingCarConfiguration);
            app.FloorheightSpinnerLabel.Text = 'Floor height';
            app.floorHeightSpinner = uispinner(app.BuildingCarConfiguration);
            app.floorHeightSpinner.Step = 0.1;
            app.floorHeightSpinner.Limits = [2 5];
            app.floorHeightSpinner.Value = 3;
            app.ParkingMethodLabel = uilabel(app.BuildingCarConfiguration);
            app.ParkingMethodLabel.Text = 'Parking Method';
            app.parkingMethod = uidropdown(app.BuildingCarConfiguration);
            app.parkingMethod.Items = {'Park1 (No parking method)', 'Park2', 'Park3', 'Park4'};
            app.parkingMethod.Value = 'Park1 (No parking method)';
            app.TrafficConfiguration = uitab(app.TabGroup);
            app.TrafficConfiguration.Title = 'Traffic Configuration';
            app.TrafficComponentsConfigurationPanel = uipanel(app.TrafficConfiguration);
            app.TrafficComponentsConfigurationPanel.Title = 'Traffic Components Configuration';
            app.trafficIntervalPanel = uipanel(app.TrafficComponentsConfigurationPanel);
            app.trafficIntervalPanel.Title = 'Interval Selection';
            app.IncomingtrafficSliderLabel = uilabel(app.trafficIntervalPanel);
            app.IncomingtrafficSliderLabel.Text = 'Incoming traffic';
            app.trafficIncomingSliderRange = uislider(app.trafficIntervalPanel, 'range');
            app.trafficIncomingSliderRange.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.trafficIncomingSliderRange.ValueChangedFcn = createCallbackFcn(app, @trafficIncomingSliderRangeValueChanged, true);
            app.trafficIncomingSliderRange.MinorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.InterfloortrafficSliderLabel = uilabel(app.trafficIntervalPanel);
            app.InterfloortrafficSliderLabel.Text = 'Interfloor traffic';
            app.trafficInterfloorSliderRange = uislider(app.trafficIntervalPanel, 'range');
            app.trafficInterfloorSliderRange.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.trafficInterfloorSliderRange.ValueChangedFcn = createCallbackFcn(app, @trafficInterfloorSliderRangeValueChanged, true);
            app.trafficInterfloorSliderRange.MinorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.trafficIncomingLabelMin = uilabel(app.trafficIntervalPanel);
            app.trafficIncomingLabelMin.Text = 'Minimum incoming traffic:';
            app.trafficIncomingLabelMax = uilabel(app.trafficIntervalPanel);
            app.trafficIncomingLabelMax.Text = 'Maximum incoming traffic:';
            app.trafficIncomingMin = uilabel(app.trafficIntervalPanel);
            app.trafficIncomingMin.Text = '0';
            app.trafficIncomingMax = uilabel(app.trafficIntervalPanel);
            app.trafficIncomingMax.Text = '100';
            app.trafficLampRange = uilamp(app.trafficIntervalPanel);
            app.trafficInterfloorLabelMin = uilabel(app.trafficIntervalPanel);
            app.trafficInterfloorLabelMin.Text = 'Minimum interfloor traffic:';
            app.trafficInterfloorLabelMax = uilabel(app.trafficIntervalPanel);
            app.trafficInterfloorLabelMax.Text = 'Maximum interfloor traffic:';
            app.trafficInterfloorMin = uilabel(app.trafficIntervalPanel);
            app.trafficInterfloorMin.Text = '0';
            app.trafficInterfloorMax = uilabel(app.trafficIntervalPanel);
            app.trafficInterfloorMax.Text = '100';
            app.trafficFixedPanel = uipanel(app.TrafficComponentsConfigurationPanel);
            app.trafficFixedPanel.Title = 'Fixed Selection';
            app.trafficLampFixed = uilamp(app.trafficFixedPanel);
            app.trafficFixedIncoming = uilabel(app.trafficFixedPanel);
            app.trafficFixedIncoming.Text = '30';
            app.FixedincomingtrafficLabel = uilabel(app.trafficFixedPanel);
            app.FixedincomingtrafficLabel.Text = 'Fixed incoming traffic:';
            app.trafficFixedIncomingSlider = uislider(app.trafficFixedPanel);
            app.trafficFixedIncomingSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.trafficFixedIncomingSlider.ValueChangedFcn = createCallbackFcn(app, @trafficFixedIncomingSliderValueChanged, true);
            app.trafficFixedIncomingSlider.MinorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.trafficFixedIncomingSlider.Value = 30;
            app.trafficFixedInterfloor = uilabel(app.trafficFixedPanel);
            app.trafficFixedInterfloor.Text = '40';
            app.InterfloortrafficLabel = uilabel(app.trafficFixedPanel);
            app.InterfloortrafficLabel.Text = 'Fixed interfloor traffic:';
            app.trafficFixedInterfloorSlider = uislider(app.trafficFixedPanel);
            app.trafficFixedInterfloorSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.trafficFixedInterfloorSlider.ValueChangedFcn = createCallbackFcn(app, @trafficFixedInterfloorSliderValueChanged, true);
            app.trafficFixedInterfloorSlider.MinorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.trafficFixedInterfloorSlider.Value = 40;
            app.InterfloortrafficLabel_2 = uilabel(app.trafficFixedPanel);
            app.InterfloortrafficLabel_2.Text = 'Outgoing traffic:';
            app.trafficFixedOutgoingSlider = uislider(app.trafficFixedPanel);
            app.trafficFixedOutgoingSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.trafficFixedOutgoingSlider.ValueChangedFcn = createCallbackFcn(app, @trafficFixedOutgoingSliderValueChanged, true);
            app.trafficFixedOutgoingSlider.MinorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.trafficFixedOutgoingSlider.Value = 30;
            app.trafficFixedOutgoing = uilabel(app.trafficFixedPanel);
            app.trafficFixedOutgoing.Text = '30';
            app.PassengerArrivalButtonGroup = uibuttongroup(app.TrafficConfiguration);
            app.PassengerArrivalButtonGroup.Title = 'Passenger Arrival';
            app.radioButtonNoNewPassenger = uiradiobutton(app.PassengerArrivalButtonGroup);
            app.radioButtonNoNewPassenger.Text = 'No new passenger';
            app.radioButtonNoNewPassenger.Value = true;
            app.arrivalRateSpinner = uispinner(app.PassengerArrivalButtonGroup);
            app.arrivalRateSpinner.Limits = [0.1 Inf];
            app.arrivalRateSpinner.Value = 10;
            app.arrivalRateRadioButton = uiradiobutton(app.PassengerArrivalButtonGroup);
            app.arrivalRateRadioButton.Text = 'Passenger Arrival Rate (seconds/passenger)';
            app.ControlMethod = uitab(app.TabGroup);
            app.ControlMethod.Title = 'Control Method';
            app.ControlMethodButtonGroup = uibuttongroup(app.ControlMethod);
            app.ControlMethodButtonGroup.Title = 'Control Method';
            app.buttonNearestCarMethod = uiradiobutton(app.ControlMethodButtonGroup);
            app.buttonNearestCarMethod.Text = 'Nearest Car Method';
            app.buttonMarkovDecisionProcess = uiradiobutton(app.ControlMethodButtonGroup);
            app.buttonMarkovDecisionProcess.Text = 'Markov Decision Process';
            app.ObjectiveFunctionLabel = uilabel(app.ControlMethodButtonGroup);
            app.ObjectiveFunctionLabel.Text = 'Objective Function';
            app.objectiveFunctionDropDown = uidropdown(app.ControlMethodButtonGroup);
            app.objectiveFunctionDropDown.Items = {'Conventional Information', 'Number of Passengers', 'Destination Information', 'User Defined 1', 'User Defined 2', 'User Defined 3'};
            app.objectiveFunctionDropDown.Value = 'Destination Information';
            app.metaheuristicsRadioButton = uiradiobutton(app.ControlMethodButtonGroup);
            app.metaheuristicsRadioButton.Text = 'Metaheuristics';
            app.metaheuristicsRadioButton.Value = true;
            app.MetaheuristicsButtonGroup = uibuttongroup(app.ControlMethodButtonGroup);
            app.MetaheuristicsButtonGroup.Title = 'Metaheuristic Algorithms';
            app.psoSelectionButton = uiradiobutton(app.MetaheuristicsButtonGroup);
            app.psoSelectionButton.Text = 'Particle Swarm Optimization';
            app.acoSelectionButton = uiradiobutton(app.MetaheuristicsButtonGroup);
            app.acoSelectionButton.Visible = 'off';
            app.acoSelectionButton.Text = 'Ant Colony Optimization';
            app.gaSelectionButton = uiradiobutton(app.MetaheuristicsButtonGroup);
            app.gaSelectionButton.Text = 'Genetic Algorithm';
            app.gaSelectionButton.Value = true;
            app.CrossoverrateLabel_2 = uilabel(app.MetaheuristicsButtonGroup);
            app.CrossoverrateLabel_2.Text = 'Population size';
            app.populationSizeSpinner = uispinner(app.MetaheuristicsButtonGroup);
            app.populationSizeSpinner.Limits = [2 1000];
            app.populationSizeSpinner.Value = 100;
            app.CrossoverrateLabel_3 = uilabel(app.MetaheuristicsButtonGroup);
            app.CrossoverrateLabel_3.Text = 'Maximum number of iterations';
            app.maxIterationsSpinner = uispinner(app.MetaheuristicsButtonGroup);
            app.maxIterationsSpinner.Limits = [1 10000];
            app.maxIterationsSpinner.Value = 50;
            app.MutationrateSpinnerLabel = uilabel(app.MetaheuristicsButtonGroup);
            app.MutationrateSpinnerLabel.Text = 'Mutation rate';
            app.gaMutationRateSpinner = uispinner(app.MetaheuristicsButtonGroup);
            app.gaMutationRateSpinner.Step = 0.01;
            app.gaMutationRateSpinner.Limits = [0 1];
            app.gaMutationRateSpinner.Value = 0.01;
            app.CrossoverrateLabel = uilabel(app.MetaheuristicsButtonGroup);
            app.CrossoverrateLabel.Text = 'Crossover rate';
            app.gaCrossoverRateSpinner = uispinner(app.MetaheuristicsButtonGroup);
            app.gaCrossoverRateSpinner.Step = 0.01;
            app.gaCrossoverRateSpinner.Limits = [0 1];
            app.gaCrossoverRateSpinner.Value = 0.8;
            app.gaCrossoverButtonGroup = uibuttongroup(app.MetaheuristicsButtonGroup);
            app.gaCrossoverButtonGroup.Title = 'Crossover method';
            app.crossovertwopointButton = uiradiobutton(app.gaCrossoverButtonGroup);
            app.crossovertwopointButton.Text = 'crossovertwopoint';
            app.crossoversinglepointButton = uiradiobutton(app.gaCrossoverButtonGroup);
            app.crossoversinglepointButton.Text = 'crossoversinglepoint';
            app.crossoverscatteredButton = uiradiobutton(app.gaCrossoverButtonGroup);
            app.crossoverscatteredButton.Text = 'crossoverscattered';
            app.crossoverscatteredButton.Value = true;
            app.gaSelectionButtonGroup = uibuttongroup(app.MetaheuristicsButtonGroup);
            app.gaSelectionButtonGroup.Title = 'Selection Method';
            app.gaSelectionTournamentButton = uiradiobutton(app.gaSelectionButtonGroup);
            app.gaSelectionTournamentButton.Text = 'selectiontournament';
            app.gaSelectionRouletteButton = uiradiobutton(app.gaSelectionButtonGroup);
            app.gaSelectionRouletteButton.Text = 'selectionroulette';
            app.gaSelectionStochUnifButton = uiradiobutton(app.gaSelectionButtonGroup);
            app.gaSelectionStochUnifButton.Text = 'selectionstochunif ';
            app.gaSelectionStochUnifButton.Value = true;
            app.CrossoverrateLabel_4 = uilabel(app.MetaheuristicsButtonGroup);
            app.CrossoverrateLabel_4.Visible = 'off';
            app.CrossoverrateLabel_4.Text = 'α';
            app.CrossoverrateLabel_5 = uilabel(app.MetaheuristicsButtonGroup);
            app.CrossoverrateLabel_5.Visible = 'off';
            app.CrossoverrateLabel_5.Text = 'ρ';
            app.acoRho = uispinner(app.MetaheuristicsButtonGroup);
            app.acoRho.Step = 0.1;
            app.acoRho.Limits = [0 1];
            app.acoRho.Visible = 'off';
            app.acoRho.Value = 0.5;
            app.CrossoverrateLabel_6 = uilabel(app.MetaheuristicsButtonGroup);
            app.CrossoverrateLabel_6.Visible = 'off';
            app.CrossoverrateLabel_6.Text = 'Q';
            app.acoQ = uispinner(app.MetaheuristicsButtonGroup);
            app.acoQ.Limits = [1 100];
            app.acoQ.Visible = 'off';
            app.acoQ.Value = 1;
            app.CrossoverrateLabel_7 = uilabel(app.MetaheuristicsButtonGroup);
            app.CrossoverrateLabel_7.Visible = 'off';
            app.CrossoverrateLabel_7.Text = 'β';
            app.acoBeta = uispinner(app.MetaheuristicsButtonGroup);
            app.acoBeta.Limits = [0 5];
            app.acoBeta.Visible = 'off';
            app.acoBeta.Value = 2;
            app.CrossoverrateLabel_8 = uilabel(app.MetaheuristicsButtonGroup);
            app.CrossoverrateLabel_8.Text = 'c1';
            app.CrossoverrateLabel_9 = uilabel(app.MetaheuristicsButtonGroup);
            app.CrossoverrateLabel_9.Text = 'c2';
            app.psoC2 = uispinner(app.MetaheuristicsButtonGroup);
            app.psoC2.Step = 0.1;
            app.psoC2.Limits = [0 1];
            app.psoC2.Value = 0.8;
            app.psoC1 = uispinner(app.MetaheuristicsButtonGroup);
            app.psoC1.Step = 0.1;
            app.psoC1.Limits = [0 1];
            app.psoC1.Value = 0.8;
            app.psoWend = uispinner(app.MetaheuristicsButtonGroup);
            app.psoWend.Step = 0.1;
            app.psoWend.Limits = [0 1];
            app.psoWend.ValueChangedFcn = createCallbackFcn(app, @psoWendValueChanged, true);
            app.psoWend.Value = 0.2;
            app.psoWstart = uispinner(app.MetaheuristicsButtonGroup);
            app.psoWstart.Step = 0.1;
            app.psoWstart.Limits = [0.1 1];
            app.psoWstart.ValueChangedFcn = createCallbackFcn(app, @psoWstartValueChanged, true);
            app.psoWstart.Value = 0.2;
            app.acoAlpha = uispinner(app.MetaheuristicsButtonGroup);
            app.acoAlpha.Limits = [0 5];
            app.acoAlpha.Visible = 'off';
            app.acoAlpha.Value = 1;
            app.CrossoverrateLabel_10 = uilabel(app.MetaheuristicsButtonGroup);
            app.CrossoverrateLabel_10.Text = 'w_start';
            app.JumpprobLabel = uilabel(app.MetaheuristicsButtonGroup);
            app.JumpprobLabel.Text = 'F';
            app.deF = uispinner(app.MetaheuristicsButtonGroup);
            app.deF.Step = 0.1;
            app.deF.Limits = [0 1];
            app.deF.Value = 0.4;
            app.CrossoverrateLabel_11 = uilabel(app.MetaheuristicsButtonGroup);
            app.CrossoverrateLabel_11.Text = 'CR';
            app.deCrossoverRate = uispinner(app.MetaheuristicsButtonGroup);
            app.deCrossoverRate.Step = 0.01;
            app.deCrossoverRate.Limits = [0 1];
            app.deCrossoverRate.Value = 0.8;
            app.deSelectionButton = uiradiobutton(app.MetaheuristicsButtonGroup);
            app.deSelectionButton.Text = 'Differential Evolution';
            app.mutationButtonGroup = uibuttongroup(app.MetaheuristicsButtonGroup);
            app.mutationButtonGroup.Title = 'Mutation method';
            app.scramble = uiradiobutton(app.mutationButtonGroup);
            app.scramble.Text = 'scramble';
            app.block = uiradiobutton(app.mutationButtonGroup);
            app.block.Text = 'block';
            app.uniform = uiradiobutton(app.mutationButtonGroup);
            app.uniform.Text = 'uniform';
            app.uniform.Value = true;
            app.swap = uiradiobutton(app.mutationButtonGroup);
            app.swap.Text = 'swap';
            app.frequency = uiradiobutton(app.mutationButtonGroup);
            app.frequency.Text = 'frequency';
            app.CrossoverrateLabel_12 = uilabel(app.MetaheuristicsButtonGroup);
            app.CrossoverrateLabel_12.Text = 'w_end';
            app.CrossoverrateLabel_13 = uilabel(app.MetaheuristicsButtonGroup);
            app.CrossoverrateLabel_13.Text = 'c3';
            app.psoC3 = uispinner(app.MetaheuristicsButtonGroup);
            app.psoC3.Step = 0.1;
            app.psoC3.Limits = [0 1];
            app.psoC3.Value = 0.8;
            app.VariantDropDownLabel = uilabel(app.MetaheuristicsButtonGroup);
            app.VariantDropDownLabel.Text = 'Variant';
            app.deVariant = uidropdown(app.MetaheuristicsButtonGroup);
            app.deVariant.Items = {'rand/1', 'best/1', 'rand/2', 'best/2', 'current-to-best/1', 'rand-to-best/1', 'current-to-rand/1'};
            app.deVariant.Value = 'rand/1';
            app.SimulationConfiguration = uitab(app.TabGroup);
            app.SimulationConfiguration.Title = 'Simulation Configuration';
            app.dispatchPeriodOption = uibuttongroup(app.SimulationConfiguration);
            app.dispatchPeriodOption.Title = 'Dispatch Interval Options';
            app.oneShot = uiradiobutton(app.dispatchPeriodOption);
            app.oneShot.Text = 'Dispatch when new call registered';
            app.oneShot.Value = true;
            app.dispatchPeriod = uiradiobutton(app.dispatchPeriodOption);
            app.dispatchPeriod.Text = '';
            app.DispatchperiodLabel = uilabel(app.dispatchPeriodOption);
            app.DispatchperiodLabel.Text = 'Dispatch period';
            app.dispatchPeriodSpinner = uispinner(app.dispatchPeriodOption);
            app.dispatchPeriodSpinner.Limits = [1 Inf];
            app.dispatchPeriodSpinner.Value = 1;
            app.dataTypeButtonGroup = uibuttongroup(app.SimulationConfiguration);
            app.dataTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @dataTypeChanged, true);
            app.dataTypeButtonGroup.Title = 'Data Type';
            app.numInitialPassengersSpinner = uispinner(app.dataTypeButtonGroup);
            app.NumberofinitialpassengersSpinnerLabel = uilabel(app.dataTypeButtonGroup);
            app.NumberofinitialpassengersSpinnerLabel.Text = 'Number of initial passengers';
            app.dataTypeNewTrafficwithCustomInitialsRB = uiradiobutton(app.dataTypeButtonGroup);
            app.dataTypeNewTrafficwithCustomInitialsRB.Text = 'New Traffic with Custom Initials';
            app.dataTypeRecordedTrafficRB = uiradiobutton(app.dataTypeButtonGroup);
            app.dataTypeRecordedTrafficRB.Text = 'Recorded Traffic';
            app.dataTypeNewTrafficRB = uiradiobutton(app.dataTypeButtonGroup);
            app.dataTypeNewTrafficRB.Text = 'New Traffic';
            app.dataTypeNewTrafficRB.Value = true;
            app.parameterSearch = uicheckbox(app.dataTypeButtonGroup);
            app.parameterSearch.Text = {'Parameter search '; 'Population size, number of iterations, and number of simulations are provided by the user. '; 'For all other parameters, user selections are ignored and grid search is applied instead.'};
            app.NumberofrunsSpinnerLabel = uilabel(app.dataTypeButtonGroup);
            app.NumberofrunsSpinnerLabel.Text = 'Number of runs';
            app.numberOfRuns = uispinner(app.dataTypeButtonGroup);
            app.numberOfRuns.Limits = [1 Inf];
            app.numberOfRuns.Value = 1;
            app.SimulationdurationSpinnerLabel = uilabel(app.SimulationConfiguration);
            app.SimulationdurationSpinnerLabel.Text = 'Simulation duration';
            app.simulationDurationSpinner = uispinner(app.SimulationConfiguration);
            app.simulationDurationSpinner.Limits = [0 Inf];
            app.simulationDurationSpinner.Value = 15;
            app.NumberofsimulationsSpinnerLabel = uilabel(app.SimulationConfiguration);
            app.NumberofsimulationsSpinnerLabel.Text = 'Number of simulations';
            app.numSimulationsSpinner = uispinner(app.SimulationConfiguration);
            app.numSimulationsSpinner.Limits = [1 Inf];
            app.numSimulationsSpinner.Value = 1;
            app.ReferencetimeSpinnerLabel = uilabel(app.SimulationConfiguration);
            app.ReferencetimeSpinnerLabel.Text = 'Reference time';
            app.referenceTimeSpinner = uispinner(app.SimulationConfiguration);
            app.referenceTimeSpinner.Limits = [0 Inf];
            app.displayTab = uitab(app.TabGroup);
            app.displayTab.Title = 'Display Tab';
            app.displayFlowUIAxes = uiaxes(app.displayTab);
            title(app.displayFlowUIAxes, 'Title')
            xlabel(app.displayFlowUIAxes, 'X')
            ylabel(app.displayFlowUIAxes, 'Y')
            zlabel(app.displayFlowUIAxes, 'Z')
            app.displayFlowUIAxes.Visible = 'off';
            app.displayTrafficFlowCheckBox = uicheckbox(app.displayTab);
            app.displayTrafficFlowCheckBox.ValueChangedFcn = createCallbackFcn(app, @displayTrafficFlowCheckBoxValueChanged, true);
            app.displayTrafficFlowCheckBox.Text = 'Display traffic flow';
            app.displayTrafficFlowCheckBox.Value = true;
            app.displayTabularDataCheckBox = uicheckbox(app.displayTab);
            app.displayTabularDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @displayTabularDataCheckBoxValueChanged, true);
            app.displayTabularDataCheckBox.Text = 'Display tabular data';
            app.displayTabularDataCheckBox.Value = true;
            app.DisplayspeedLabel = uilabel(app.displayTab);
            app.DisplayspeedLabel.Text = 'Display speed';
            app.simulationSpeedSlider = uislider(app.displayTab);
            app.simulationSpeedSlider.Limits = [1 5];
            app.simulationSpeedSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.simulationSpeedSlider.ValueChangedFcn = createCallbackFcn(app, @simulationSpeedSliderValueChanged, true);
            app.simulationSpeedSlider.Value = 3;
            app.passengerDownTable = uitable(app.displayTab);
            app.passengerDownTable.ColumnName = '';
            app.passengerDownTable.RowName = {};
            app.passengerUpTable = uitable(app.displayTab);
            app.passengerUpTable.ColumnName = '';
            app.passengerUpTable.RowName = {};
            app.carTable = uitable(app.displayTab);
            app.carTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.carTable.RowName = {};
            app.WaitinguppassengersLabel = uilabel(app.displayTab);
            app.WaitinguppassengersLabel.Text = 'Waiting up passengers';
            app.WaitingdownpassengersLabel = uilabel(app.displayTab);
            app.WaitingdownpassengersLabel.Text = 'Waiting down passengers';
            app.resultsTable = uitable(app.displayTab);
            app.resultsTable.ColumnName = '';
            app.resultsTable.RowName = {'Total car trip time'; 'Total passenger waiting time'; 'Total hall call waiting time'; 'Number of served passengers'; 'Number of responded hall calls'; 'Average car trip time'; 'Average passenger waiting time'; 'Average hall call waiting time'};
            app.carsLabel = uilabel(app.displayTab);
            app.carsLabel.Text = 'Cars';
            app.countersTable = uitable(app.displayTab);
            app.countersTable.ColumnName = {'Building Conf.'; 'Car Conf.'; 'Traffic Conf.'; 'Sim counter/ Max sims.'; 'Total Run/ Max Run'; 'Total runtime'};
            app.countersTable.RowName = {};
            app.counterLabel = uilabel(app.displayTab);
            app.counterLabel.Text = 'Counters';
            app.buttonStart = uibutton(app.UIFigure, 'push');
            app.buttonStart.ButtonPushedFcn = createCallbackFcn(app, @buttonStartButtonPushed, true);
            app.buttonStart.Text = 'Start';
            app.terminateButton = uibutton(app.UIFigure, 'push');
            app.terminateButton.ButtonPushedFcn = createCallbackFcn(app, @terminateButtonPushed, true);
            app.terminateButton.Text = 'Terminate';
            app.pauseButton = uibutton(app.UIFigure, 'push');
            app.pauseButton.ButtonPushedFcn = createCallbackFcn(app, @pauseButtonPushed, true);
            app.pauseButton.Text = 'Pause';
            app.loadLastConfiguration = uibutton(app.UIFigure, 'push');
            app.loadLastConfiguration.ButtonPushedFcn = createCallbackFcn(app, @loadLastConfigurationButtonPushed, true);
            app.loadLastConfiguration.Text = 'Load the last configuration';
            app.exitButton = uibutton(app.UIFigure, 'push');
            app.exitButton.ButtonPushedFcn = createCallbackFcn(app, @exitButtonPushed, true);
            app.exitButton.Text = 'Exit';
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'Save data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ESRA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end